classdef kempo1_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        file                           matlab.ui.container.Menu
        load                           matlab.ui.container.Menu
        save                           matlab.ui.container.Menu
        exit                           matlab.ui.container.Menu
        help                           matlab.ui.container.Menu
        paramlist                      matlab.ui.container.Menu
        about                          matlab.ui.container.Menu
        copyright                      matlab.ui.control.Label
        title                          matlab.ui.control.Label
        check_icolor                   matlab.ui.control.CheckBox
        inputText                      matlab.ui.control.Label
        check_iparam                   matlab.ui.control.CheckBox
        LOADButton                     matlab.ui.control.Button
        SAVEButton                     matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        EXITButton                     matlab.ui.control.Button
        IEXButtonGroup                 matlab.ui.container.ButtonGroup
        Ex0Button                      matlab.ui.control.RadioButton
        EMButton                       matlab.ui.control.RadioButton
        ESButton                       matlab.ui.control.RadioButton
        TimestepandSpaceintervalPanel  matlab.ui.container.Panel
        DXEditFieldLabel               matlab.ui.control.Label
        DXEditField                    matlab.ui.control.NumericEditField
        DTEditFieldLabel               matlab.ui.control.Label
        DTEditField                    matlab.ui.control.NumericEditField
        NXEditFieldLabel               matlab.ui.control.Label
        NXEditField                    matlab.ui.control.NumericEditField
        NTIMEEditFieldLabel            matlab.ui.control.Label
        NTIMEEditField                 matlab.ui.control.NumericEditField
        chargeandBanglePanel           matlab.ui.container.Panel
        CVEditFieldLabel               matlab.ui.control.Label
        CVEditField                    matlab.ui.control.NumericEditField
        ANGLEEditFieldLabel            matlab.ui.control.Label
        ANGLEEditField                 matlab.ui.control.NumericEditField
        WCEditFieldLabel               matlab.ui.control.Label
        WCEditField                    matlab.ui.control.NumericEditField
        ParticlePanel                  matlab.ui.container.Panel
        NSEditFieldLabel               matlab.ui.control.Label
        NSEditField                    matlab.ui.control.NumericEditField
        SelectspeciesDropDownLabel     matlab.ui.control.Label
        SelectspeciesDropDown          matlab.ui.control.DropDown
        QMEditFieldLabel               matlab.ui.control.Label
        QMEditField                    matlab.ui.control.NumericEditField
        VPEEditFieldLabel              matlab.ui.control.Label
        VPEEditField                   matlab.ui.control.NumericEditField
        VDEditFieldLabel               matlab.ui.control.Label
        VDEditField                    matlab.ui.control.NumericEditField
        NPEditFieldLabel               matlab.ui.control.Label
        NPEditField                    matlab.ui.control.NumericEditField
        PCHEditFieldLabel              matlab.ui.control.Label
        PCHEditField                   matlab.ui.control.NumericEditField
        WPEditFieldLabel               matlab.ui.control.Label
        WPEditField                    matlab.ui.control.NumericEditField
        VPAEditFieldLabel              matlab.ui.control.Label
        VPAEditField                   matlab.ui.control.NumericEditField
        PreviewButton                  matlab.ui.control.Button
        CurrentPanel                   matlab.ui.container.Panel
        AJAMPEditFieldLabel            matlab.ui.control.Label
        AJAMPEditField                 matlab.ui.control.NumericEditField
        WJEditFieldLabel               matlab.ui.control.Label
        WJEditField                    matlab.ui.control.NumericEditField
        DiagnosticsPanel               matlab.ui.container.Panel
        Panel1DropDownLabel            matlab.ui.control.Label
        Panel1DropDown                 matlab.ui.control.DropDown
        Panel2DropDownLabel            matlab.ui.control.Label
        Panel2DropDown                 matlab.ui.control.DropDown
        Panel3DropDownLabel            matlab.ui.control.Label
        Panel3DropDown                 matlab.ui.control.DropDown
        Panel4DropDownLabel            matlab.ui.control.Label
        Panel4DropDown                 matlab.ui.control.DropDown
        NPLOTEditFieldLabel            matlab.ui.control.Label
        NPLOTEditField                 matlab.ui.control.NumericEditField
        VmaxEditFieldLabel             matlab.ui.control.Label
        VmaxEditField                  matlab.ui.control.NumericEditField
        EmaxEditFieldLabel             matlab.ui.control.Label
        EmaxEditField                  matlab.ui.control.NumericEditField
        BmaxEditFieldLabel             matlab.ui.control.Label
        BmaxEditField                  matlab.ui.control.NumericEditField
        NVEditFieldLabel               matlab.ui.control.Label
        NVEditField                    matlab.ui.control.NumericEditField
    end

   
   properties (Access = private)
      % Struct of simulation parameters
      prm = struct('dx', 1, 'nx', 128,'dt',0.04 ,'ntime', 1024, 'cv', 20, ...
         'angle', 0, 'wc', 0, 'ns', 1, 'qm', -1, 'wp', 1, 'vpe', 10, ...
         'vpa', 0.5, 'vd', 0, 'pch', 0, 'np', 1024, 'ajamp', 0, 'wj', 0,...
         'nplot', 256, 'vmax', 20, 'emax', 10, 'bmax', 0.2, 'nv', 100,...
         'iex', 1, 'diagtype',[11 23 18 4]);
      Sp = 1; % Current species id
   end
   
   methods (Access = private)
      function getSpecies(app, sp_)
         
         app.prm.ns = app.NSEditField.Value;
         app.prm.np(sp_) = app.NPEditField.Value;
         app.prm.wp(sp_) = app.WPEditField.Value;
         app.prm.qm(sp_) = app.QMEditField.Value;
         app.prm.vpa(sp_) = app.VPAEditField.Value;
         app.prm.vpe(sp_) = app.VPEEditField.Value;
         app.prm.vd(sp_) = app.VDEditField.Value;
         app.prm.pch(sp_) = app.PCHEditField.Value;
         
      end
      
      function getParam(app)
         
         app.prm.ns = app.NSEditField.Value;
         
         %
         app.prm.dx = app.DXEditField.Value;
         app.prm.dt = app.DTEditField.Value;
         app.prm.nx = app.NXEditField.Value;
         app.prm.ntime = app.NTIMEEditField.Value;
         
         app.prm.cv = app.CVEditField.Value;
         app.prm.wc = app.WCEditField.Value;
         app.prm.angle = app.ANGLEEditField.Value;
         
         %
         app.prm.ajamp = app.AJAMPEditField.Value;
         app.prm.wj = app.WJEditField.Value;
         
         
         if app.Ex0Button.Value
            app.prm.iex = 0;
         elseif app.ESButton.Value
            app.prm.iex = 2;
         else
            app.prm.iex = 1;
         end
         
         % Get particle info
         for sp=1:app.prm.ns
            app.prm.np(sp) = app.NPEditField.Value;
         end
         
         %
         app.prm.nplot= app.NPLOTEditField.Value;
         app.prm.emax = app.EmaxEditField.Value;
         app.prm.bmax = app.BmaxEditField.Value;
         app.prm.vmax = app.VmaxEditField.Value;
         app.prm.nv   = app.NVEditField.Value;
         
         app.prm.icolor = app.check_icolor;
         app.prm.iparam = app.check_iparam;
         
         %
         app.prm.diagtype(1) = ...
            find(strcmp(app.Panel1DropDown.Items,app.Panel1DropDown.Value));
         app.prm.diagtype(2) = ...
            find(strcmp(app.Panel2DropDown.Items,app.Panel2DropDown.Value));
         app.prm.diagtype(3) = ...
            find(strcmp(app.Panel3DropDown.Items,app.Panel3DropDown.Value));
         app.prm.diagtype(4) = ...
            find(strcmp(app.Panel4DropDown.Items,app.Panel4DropDown.Value));
      end
      
      function hfig = showParamlist(app)
         
         % Display the parameter list figure
         img = imread('paramlist.png');
         %               imgsize = size(img);
         %
         %               hfig = figure;
         %               set(hfig,'menu','none');
         %               set(hfig,'WindowStyle','modal');
         %               set(hfig,'resize','off');
         %               pos = get(hfig,'Position');
         %               set(hfig,'Position',[30 30 imgsize(2) imgsize(1)]);
         %
         %               himag = image(img);
         %               set(gca,'position',[0,0,1,1]);
         %               axis off;
         %
         %               drawnow
      end
      
      function saveParam(app, filename)
         
         fid = fopen(filename,'w');
         
%          fprintf(fid,'%%  KEMPO1 / input parameters\n\n');
%          fprintf(fid,'%% --  --\n');
         fprintf(fid,'dx = %f;\n',app.prm.dx);
         fprintf(fid,'dt = %f;\n',app.prm.dt);
         fprintf(fid,'nx = %f;\n',app.prm.nx);
         fprintf(fid,'ntime = %f;\n',app.prm.ntime);
         fprintf(fid,'cv = %f;\n',app.prm.cv);
         fprintf(fid,'wc = %f;\n',app.prm.wc);
         fprintf(fid,'angle = %f;\n',app.prm.angle);
         fprintf(fid,'\n');
         
         % species
         ns = app.prm.ns;
%          fprintf(fid,'%% -- species --\n');
         fprintf(fid,'ns = %f;\n', ns);
         
         l = sprintf('%f, ',app.prm.np(1:ns));
         fprintf(fid,'np = [%s];\n',l);
         l = sprintf('%f, ',app.prm.wp(1:ns));
         fprintf(fid,'wp = [%s];\n',l);
         l = sprintf('%f, ',app.prm.qm(1:ns));
         fprintf(fid,'qm = [%s];\n',l);
         l = sprintf('%f, ',app.prm.vpa(1:ns));
         fprintf(fid,'vpa = [%s];\n',l);
         l = sprintf('%f, ',app.prm.vpe(1:ns));
         fprintf(fid,'vpe = [%s];\n',l);
         l = sprintf('%f, ',app.prm.vd(1:ns));
         fprintf(fid,'vd = [%s];\n',l);
         l = sprintf('%f, ',app.prm.pch(1:ns));
         fprintf(fid,'pch = [%s];\n',l);
         fprintf(fid,'\n');
         
%          fprintf(fid,'%% --  --\n');
         fprintf(fid,'iex = %f;\n',app.prm.iex);
         fprintf(fid,'\n');
         
         %
%          fprintf(fid,'%% --  --\n');
         fprintf(fid,'ajamp = %f;\n',app.prm.ajamp);
         fprintf(fid,'wj = %f;\n',app.prm.wj);
         fprintf(fid,'\n');
         
         % diag.
%          fprintf(fid,'%% -- diagnostics --\n');
         fprintf(fid,'nplot = %f;\n',app.prm.nplot);
         fprintf(fid,'nv = %f;\n',app.prm.nv);
         fprintf(fid,'icolor = %f;\n',app.prm.icolor);
         fprintf(fid,'iparam = %f;\n',app.prm.iparam);
         fprintf(fid,'vmax = %f;\n',app.prm.vmax);
         fprintf(fid,'emax = %f;\n',app.prm.emax);
         fprintf(fid,'bmax = %f;\n',app.prm.bmax);
         
         l = sprintf('%f, ',app.prm.diagtype);
         fprintf(fid,'diagtype = [%s];\n',l);
         
         fclose(fid);
      end
      
      function setParam(app,prmIn)
         
         app.prm = prmIn;
          
         app.DXEditField.Value = prmIn.dx;
         app.DTEditField.Value = prmIn.dt;
         app.NXEditField.Value = prmIn.nx;
         app.NTIMEEditField.Value = prmIn.ntime;
         
         app.CVEditField.Value = prmIn.cv;
         app.WCEditField.Value = prmIn.wc;
         app.ANGLEEditField.Value = prmIn.angle;
         
         app.WJEditField.Value = prmIn.wj;
         app.AJAMPEditField.Value = prmIn.ajamp;
         
         app.NPEditField.Value = prmIn.nplot;
         
         switch prmIn.iex
            case 0
               app.Ex0Button.Value = 1;
            case 2
               app.ESButton.Value = 1;
            otherwise
               app.EMButton.Value = 1;
         end
         
         app.BmaxEditField.Value = prmIn.bmax;
         app.EmaxEditField.Value = prmIn.emax;
         app.VmaxEditField.Value = prmIn.vmax;
         app.NVEditField.Value = prmIn.nv;
         app.check_icolor.Value = prmIn.icolor;
         app.check_icolor.Value = prmIn.iparam;
         
         
         app.Panel1DropDown.Value = ...
            char(app.Panel1DropDown.Items(prmIn.diagtype(1)));
         app.Panel2DropDown.Value = ...
            char(app.Panel2DropDown.Items(prmIn.diagtype(2)));
         app.Panel3DropDown.Value = ...
            char(app.Panel3DropDown.Items(prmIn.diagtype(3)));
         app.Panel4DropDown.Value = ...
            char(app.Panel4DropDown.Items(prmIn.diagtype(4)));
         
         app.NSEditField.Value = prmIn.ns;
         
         setSpecies(app);
         
      end
      
      function setSpecies(app)
         % set species info in the GUI for display
          
         sp_ = app.Sp;
         
         app.NPEditField.Value = app.prm.np(sp_);
         app.WPEditField.Value = app.prm.wp(sp_);
         app.QMEditField.Value = app.prm.qm(sp_);
         app.VPAEditField.Value = app.prm.vpa(sp_);
         app.VPEEditField.Value = app.prm.vpe(sp_);
         app.VDEditField.Value = app.prm.vd(sp_);
         app.PCHEditField.Value = app.prm.pch(sp_);
         
      end
      
      function hfig = splashScreen(app, VERSION)
         
         img = imread('about.png');
         imgsize = size(img);
         
         hfig = figure;
         set(hfig,'menu','none');
         set(hfig,'WindowStyle','modal');
         set(hfig,'resize','off');
         pos = get(hfig,'Position');
         set(hfig,'Position',[pos(1) pos(2) imgsize(2) imgsize(1)]);
         
         %himag = image(img);
         set(gca,'position',[0,0,1,1]);
         axis off;
         
         h = uicontrol(hfig,'Style','Text', ...
            'BackgroundColor',[1,1,1], ...
            'Position',[80,0,imgsize(2)-80,30], ...
            'HorizontalAlignment','right', ...
            'String',VERSION);
         
         drawnow
      end
      
   end
   

    methods (Access = private)

        % Menu selected function: about
        function about_Callback(app, event)
         
         try
            hfig = splash_screen(app);
            uicontrol(hfig,'String','OK','CallBack','delete(gcf)');
            uiwait(hfig);
         catch
            
         end
        end

        % Callback function
        function popup_sp_Callback(app, event)
         
         
         % save previous parameters for species
         sp = app.prm.sp;
         handles = get_species(app,handles,sp);
         
         % set parameters for species
         n = get(h,'Value');
         set_species(app,handles, n);
         app.prm.sp = n;
         
         %
         guidata(gcf, handles);
        end

        % Callback function: LOADButton, load
        function LOADButtonPushed(app, event)
         [filename, pathname] = uigetfile('*.dat');
         if filename
            filenameIn = [pathname,filename];
            prmIn = setParameters(filenameIn,true);
            setParam(app, prmIn);
            
            c = cell(app.prm.ns,1);
            for i=1:app.prm.ns
               c{i} = sprintf('Species %d',i);
            end
            
            app.SelectspeciesDropDown.Items = c;
            app.SelectspeciesDropDown.ItemsData = 1:app.prm.ns;
         end
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
         %prm = param_get(handles);
         [filename, pathname] = uiputfile('*.dat');
         if filename
            save_filename = [pathname,filename];
            
            prmOut = getParam(app);
            saveParam(app,save_filename,prmOut);
         end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
         
           % Set app.prm from GUI
           getParam(app);
           
           if app.prm.ntime < app.prm.nplot
              errordlg('NTIME < NPLOT','Error')
              return
           end
           if (app.prm.ntime/app.prm.nplot) - ...
                 fix(app.prm.ntime/app.prm.nplot) ~= 0
              errordlg('NTIME/NPLOT must be INTEGER')
              return
           end
           if app.prm.dx < app.prm.cv*app.prm.dt
              warndlg('Courant condition is not satisfied.','Warning')
           end
           
           filename = 'input_tmp.dat';
           saveParam(app,filename);
           
           kempo1main(filename);
        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
           close all
        end

        % Value changed function: SelectspeciesDropDown
        function SelectspeciesDropDownValueChanged(app, event)
           % set the current species id          
           app.Sp = app.SelectspeciesDropDown.Value;
           
           % set parameters for species
           setSpecies(app);
         
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
         %
         %             prm = param_get(app,handles);
         %
         %             % make dialog box
         %             fig = dialog('Units','characters','Color',[1,1,1]);
         %             pos = get(fig,'Position');
         %             posstr = [5,5,(4+prm.ns*7)*2,10*1.5];
         %             pos = [pos(1),pos(2),posstr(1)*2+posstr(3),posstr(2)+posstr(4)+2];
         %             set(fig,'Position',pos);
         %             uicontrol(fig,'String','OK','CallBack','delete(gcf)');
         %
         %             %
         %             k = 1:prm.ns;
         %             str(1) = {['NS :' sprintf(' %g',prm.ns)]};
         %             str(2) = {['']};
         %             str(3) = {['No.:' sprintf(' %6.4g',k)]};
         %             str(4) = {['QM :' sprintf(' %6.4g',prm.qm(k))]};
         %             str(5) = {['WP :' sprintf(' %6.4g',prm.wp(k))]};
         %             str(6) = {['VPE:' sprintf(' %6.4g',prm.vpe(k))]};
         %             str(7) = {['VPA:' sprintf(' %6.4g',prm.vpa(k))]};
         %             str(8) = {['VD :' sprintf(' %6.4g',prm.vd(k))]};
         %             str(9) = {['PCH:' sprintf(' %6.4g',prm.pch(k))]};
         %             str(10)= {['NP :' sprintf(' %6.4g',prm.np(k))]};
         %
         %             %
         %             if prm.dx < prm.cv*prm.dt
         %               str(11)= {['']};
         %               str(12)= {['Warning: Courant condition is not satisfied.']};
         %
         %               posstr(4) = 13*1.5;
         %               pos = [pos(1),pos(2),posstr(1)*2+posstr(3),posstr(2)+posstr(4)+2];
         %               set(fig,'Position',pos);
         %             end
         %
         %             %
         %             h = uicontrol(fig,'style','text','String',str, ...
         %                           'Units','characters', 'Position',posstr, ...
         %                           'FontName','FixedWidth','HorizontalAlignment','left', ...
         %                            'FontSize',10,'BackgroundColor',[1,1,1]);
        end

        % Value changed function: NSEditField
        function NSEditFieldValueChanged(app, event)
           app.prm.ns = app.NSEditField.Value;
           
           c = cell(app.prm.ns,1);
           for i=1:app.prm.ns
              c{i} = sprintf('Species %d',i);
           end
           
           app.SelectspeciesDropDown.Items = c;
           app.SelectspeciesDropDown.ItemsData = 1:app.prm.ns;
           
           ns = app.prm.ns;
           % fill in the values of other species if not yet set
           if ns > 1 && numel(app.prm.qm) == 1
              app.prm.qm(ns) = app.prm.qm(1);
              app.prm.np(ns) = app.prm.np(1);
              app.prm.wp(ns) = app.prm.wp(1);
              app.prm.qm(ns) = app.prm.qm(1);
              app.prm.vpa(ns) = app.prm.vpa(1);
              app.prm.vpe(ns) = app.prm.vpe(1);
              app.prm.vd(ns) = app.prm.vd(1);
              app.prm.pch(ns) = app.prm.pch(1);
           end

        end

        % Menu selected function: paramlist
        function paramlistMenuSelected(app, event)
         % list all the available parameter files
         %               try
         %                 hfig = paramlist_screen(app);
         %                 uicontrol(hfig,'String','OK','CallBack','delete(gcf)');
         %                 uiwait(hfig);
         %               catch
         %               end
        end

        % Value changed function: QMEditField
        function QMEditFieldValueChanged(app, event)
           app.prm.qm(app.Sp) = app.QMEditField.Value;
        end

        % Value changed function: WPEditField
        function WPEditFieldValueChanged(app, event)
           app.prm.wp(app.Sp) = app.WPEditField.Value;
        end

        % Value changed function: VPEEditField
        function VPEEditFieldValueChanged(app, event)
            app.prm.vpe(app.Sp) = app.VPEEditField.Value;
        end

        % Value changed function: VPAEditField
        function VPAEditFieldValueChanged(app, event)
            app.prm.vpa(app.Sp) = app.VPAEditField.Value;          
        end

        % Value changed function: VDEditField
        function VDEditFieldValueChanged(app, event)
            app.prm.vd(app.Sp) = app.VDEditField.Value;        
        end

        % Value changed function: PCHEditField
        function PCHEditFieldValueChanged(app, event)
            app.prm.pch(app.Sp) = app.PCHEditField.Value;
        end

        % Value changed function: NPEditField
        function NPEditFieldValueChanged(app, event)
            app.prm.np = app.NPEditField.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure;
            app.figure1.Name = 'KEMPO 1';
            app.file = uimenu(app.figure1);
            app.file.Text = 'File(&F)';
            app.load = uimenu(app.file);
            app.load.MenuSelectedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.load.Text = 'Load(&L)';
            app.save = uimenu(app.file);
            app.save.Text = 'Save(&S)';
            app.exit = uimenu(app.file);
            app.exit.Separator = 'on';
            app.exit.Text = 'Exit(&E)';
            app.help = uimenu(app.figure1);
            app.help.Text = 'Help(&H)';
            app.paramlist = uimenu(app.help);
            app.paramlist.MenuSelectedFcn = createCallbackFcn(app, @paramlistMenuSelected, true);
            app.paramlist.Text = 'Parameters(&P)';
            app.about = uimenu(app.help);
            app.about.MenuSelectedFcn = createCallbackFcn(app, @about_Callback, true);
            app.about.Separator = 'on';
            app.about.Text = 'About(&A)';
            app.copyright = uilabel(app.figure1);
            app.copyright.Text = {'Original version obtained from ISSS-13. Modfied by Hongyang Zhou'; 'Copyright(c) 1993-2018'; 'Space Simulation Group RISH Kyoto Univ.'; ' All Rights Reserved.'};
            app.title = uilabel(app.figure1);
            app.title.Text = 'KEMPO 1';
            app.check_icolor = uicheckbox(app.figure1);
            app.check_icolor.Text = 'P. Color';
            app.check_icolor.Value = true;
            app.inputText = uilabel(app.figure1);
            app.inputText.Text = 'Input parameters';
            app.check_iparam = uicheckbox(app.figure1);
            app.check_iparam.Text = 'Param';
            app.LOADButton = uibutton(app.figure1, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.SAVEButton = uibutton(app.figure1, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.STARTButton = uibutton(app.figure1, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = {'START'; ''};
            app.EXITButton = uibutton(app.figure1, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = {'EXIT'; ''};
            app.IEXButtonGroup = uibuttongroup(app.figure1);
            app.IEXButtonGroup.Title = 'IEX';
            app.Ex0Button = uiradiobutton(app.IEXButtonGroup);
            app.Ex0Button.Text = 'Ex=0';
            app.EMButton = uiradiobutton(app.IEXButtonGroup);
            app.EMButton.Text = 'EM';
            app.EMButton.Value = true;
            app.ESButton = uiradiobutton(app.IEXButtonGroup);
            app.ESButton.Text = 'ES';
            app.TimestepandSpaceintervalPanel = uipanel(app.figure1);
            app.TimestepandSpaceintervalPanel.Title = 'Timestep and Space interval';
            app.DXEditFieldLabel = uilabel(app.TimestepandSpaceintervalPanel);
            app.DXEditFieldLabel.Text = 'DX';
            app.DXEditField = uieditfield(app.TimestepandSpaceintervalPanel, 'numeric');
            app.DXEditField.Value = 1;
            app.DTEditFieldLabel = uilabel(app.TimestepandSpaceintervalPanel);
            app.DTEditFieldLabel.Text = 'DT';
            app.DTEditField = uieditfield(app.TimestepandSpaceintervalPanel, 'numeric');
            app.DTEditField.Value = 0.04;
            app.NXEditFieldLabel = uilabel(app.TimestepandSpaceintervalPanel);
            app.NXEditFieldLabel.Text = 'NX';
            app.NXEditField = uieditfield(app.TimestepandSpaceintervalPanel, 'numeric');
            app.NXEditField.Value = 128;
            app.NTIMEEditFieldLabel = uilabel(app.TimestepandSpaceintervalPanel);
            app.NTIMEEditFieldLabel.Text = 'NTIME';
            app.NTIMEEditField = uieditfield(app.TimestepandSpaceintervalPanel, 'numeric');
            app.NTIMEEditField.Value = 1024;
            app.chargeandBanglePanel = uipanel(app.figure1);
            app.chargeandBanglePanel.Title = 'charge and B angle?';
            app.CVEditFieldLabel = uilabel(app.chargeandBanglePanel);
            app.CVEditFieldLabel.Text = 'CV';
            app.CVEditField = uieditfield(app.chargeandBanglePanel, 'numeric');
            app.CVEditField.Value = 20;
            app.ANGLEEditFieldLabel = uilabel(app.chargeandBanglePanel);
            app.ANGLEEditFieldLabel.Text = 'ANGLE';
            app.ANGLEEditField = uieditfield(app.chargeandBanglePanel, 'numeric');
            app.WCEditFieldLabel = uilabel(app.chargeandBanglePanel);
            app.WCEditFieldLabel.Text = 'WC';
            app.WCEditField = uieditfield(app.chargeandBanglePanel, 'numeric');
            app.ParticlePanel = uipanel(app.figure1);
            app.ParticlePanel.Title = 'Particle';
            app.NSEditFieldLabel = uilabel(app.ParticlePanel);
            app.NSEditFieldLabel.Text = 'NS';
            app.NSEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.NSEditField.ValueChangedFcn = createCallbackFcn(app, @NSEditFieldValueChanged, true);
            app.NSEditField.Value = 1;
            app.SelectspeciesDropDownLabel = uilabel(app.ParticlePanel);
            app.SelectspeciesDropDownLabel.Text = 'Select species';
            app.SelectspeciesDropDown = uidropdown(app.ParticlePanel);
            app.SelectspeciesDropDown.Items = {'Species 1'};
            app.SelectspeciesDropDown.ItemsData = {'1'};
            app.SelectspeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectspeciesDropDownValueChanged, true);
            app.SelectspeciesDropDown.Value = '1';
            app.QMEditFieldLabel = uilabel(app.ParticlePanel);
            app.QMEditFieldLabel.Text = 'QM';
            app.QMEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.QMEditField.ValueChangedFcn = createCallbackFcn(app, @QMEditFieldValueChanged, true);
            app.QMEditField.Value = -1;
            app.VPEEditFieldLabel = uilabel(app.ParticlePanel);
            app.VPEEditFieldLabel.Text = 'VPE';
            app.VPEEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.VPEEditField.ValueChangedFcn = createCallbackFcn(app, @VPEEditFieldValueChanged, true);
            app.VPEEditField.Value = 10;
            app.VDEditFieldLabel = uilabel(app.ParticlePanel);
            app.VDEditFieldLabel.Text = 'VD';
            app.VDEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.VDEditField.ValueChangedFcn = createCallbackFcn(app, @VDEditFieldValueChanged, true);
            app.NPEditFieldLabel = uilabel(app.ParticlePanel);
            app.NPEditFieldLabel.Text = 'NP';
            app.NPEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.NPEditField.ValueChangedFcn = createCallbackFcn(app, @NPEditFieldValueChanged, true);
            app.NPEditField.Value = 2048;
            app.PCHEditFieldLabel = uilabel(app.ParticlePanel);
            app.PCHEditFieldLabel.Text = 'PCH';
            app.PCHEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.PCHEditField.ValueChangedFcn = createCallbackFcn(app, @PCHEditFieldValueChanged, true);
            app.WPEditFieldLabel = uilabel(app.ParticlePanel);
            app.WPEditFieldLabel.Text = 'WP';
            app.WPEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.WPEditField.ValueChangedFcn = createCallbackFcn(app, @WPEditFieldValueChanged, true);
            app.WPEditField.Value = 1;
            app.VPAEditFieldLabel = uilabel(app.ParticlePanel);
            app.VPAEditFieldLabel.Text = 'VPA';
            app.VPAEditField = uieditfield(app.ParticlePanel, 'numeric');
            app.VPAEditField.ValueChangedFcn = createCallbackFcn(app, @VPAEditFieldValueChanged, true);
            app.VPAEditField.Value = 0.5;
            app.PreviewButton = uibutton(app.ParticlePanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.CurrentPanel = uipanel(app.figure1);
            app.CurrentPanel.Title = 'Current?';
            app.AJAMPEditFieldLabel = uilabel(app.CurrentPanel);
            app.AJAMPEditFieldLabel.Text = 'AJAMP';
            app.AJAMPEditField = uieditfield(app.CurrentPanel, 'numeric');
            app.WJEditFieldLabel = uilabel(app.CurrentPanel);
            app.WJEditFieldLabel.Text = 'WJ';
            app.WJEditField = uieditfield(app.CurrentPanel, 'numeric');
            app.DiagnosticsPanel = uipanel(app.figure1);
            app.DiagnosticsPanel.Title = 'Diagnostics';
            app.Panel1DropDownLabel = uilabel(app.DiagnosticsPanel);
            app.Panel1DropDownLabel.Text = 'Panel 1';
            app.Panel1DropDown = uidropdown(app.DiagnosticsPanel);
            app.Panel1DropDown.Items = {'Vx - X', 'Vy - X', 'Vz - X', 'Vx-Vy-Vz', 'Ex(X)', 'Ey(X)', 'Ez(X)', 'By(X)', 'Bz(X)', 'VyzEByz-X', 'Energy', 'f(Vx)', 'f(Vy)', 'f(Vz)', 'Ex(k)', 'Ey(k)', 'Ez(k)', 'By(k)', 'Bz(k)', 'Ex(wk)', 'Ey(wk)', 'Ez(wk)', 'By(wk)', 'Bz(wk)', 'Ex(tX)', 'Ey(tX)', 'Ez(tX)', 'By(tX)', 'Bz(tX)', 'Ex(tk)', 'Ey(tk)', 'Ez(tk)', 'By(tk)', 'Bz(tk)'};
            app.Panel1DropDown.Value = 'Energy';
            app.Panel2DropDownLabel = uilabel(app.DiagnosticsPanel);
            app.Panel2DropDownLabel.Text = 'Panel 2';
            app.Panel2DropDown = uidropdown(app.DiagnosticsPanel);
            app.Panel2DropDown.Items = {'Vx - X', 'Vy - X', 'Vz - X', 'Vx-Vy-Vz', 'Ex(X)', 'Ey(X)', 'Ez(X)', 'By(X)', 'Bz(X)', 'VyzEByz-X', 'Energy', 'f(Vx)', 'f(Vy)', 'f(Vz)', 'Ex(k)', 'Ey(k)', 'Ez(k)', 'By(k)', 'Bz(k)', 'Ex(wk)', 'Ey(wk)', 'Ez(wk)', 'By(wk)', 'Bz(wk)', 'Ex(tX)', 'Ey(tX)', 'Ez(tX)', 'By(tX)', 'Bz(tX)', 'Ex(tk)', 'Ey(tk)', 'Ez(tk)', 'By(tk)', 'Bz(tk)'};
            app.Panel2DropDown.Value = 'By(wk)';
            app.Panel3DropDownLabel = uilabel(app.DiagnosticsPanel);
            app.Panel3DropDownLabel.Text = 'Panel 3';
            app.Panel3DropDown = uidropdown(app.DiagnosticsPanel);
            app.Panel3DropDown.Items = {'Vx - X', 'Vy - X', 'Vz - X', 'Vx-Vy-Vz', 'Ex(X)', 'Ey(X)', 'Ez(X)', 'By(X)', 'Bz(X)', 'VyzEByz-X', 'Energy', 'f(Vx)', 'f(Vy)', 'f(Vz)', 'Ex(k)', 'Ey(k)', 'Ez(k)', 'By(k)', 'Bz(k)', 'Ex(wk)', 'Ey(wk)', 'Ez(wk)', 'By(wk)', 'Bz(wk)', 'Ex(tX)', 'Ey(tX)', 'Ez(tX)', 'By(tX)', 'Bz(tX)', 'Ex(tk)', 'Ey(tk)', 'Ez(tk)', 'By(tk)', 'Bz(tk)'};
            app.Panel3DropDown.Value = 'By(k)';
            app.Panel4DropDownLabel = uilabel(app.DiagnosticsPanel);
            app.Panel4DropDownLabel.Text = 'Panel 4';
            app.Panel4DropDown = uidropdown(app.DiagnosticsPanel);
            app.Panel4DropDown.Items = {'Vx - X', 'Vy - X', 'Vz - X', 'Vx-Vy-Vz', 'Ex(X)', 'Ey(X)', 'Ez(X)', 'By(X)', 'Bz(X)', 'VyzEByz-X', 'Energy', 'f(Vx)', 'f(Vy)', 'f(Vz)', 'Ex(k)', 'Ey(k)', 'Ez(k)', 'By(k)', 'Bz(k)', 'Ex(wk)', 'Ey(wk)', 'Ez(wk)', 'By(wk)', 'Bz(wk)', 'Ex(tX)', 'Ey(tX)', 'Ez(tX)', 'By(tX)', 'Bz(tX)', 'Ex(tk)', 'Ey(tk)', 'Ez(tk)', 'By(tk)', 'Bz(tk)'};
            app.Panel4DropDown.Value = 'Vx-Vy-Vz';
            app.NPLOTEditFieldLabel = uilabel(app.DiagnosticsPanel);
            app.NPLOTEditFieldLabel.Text = 'NPLOT';
            app.NPLOTEditField = uieditfield(app.DiagnosticsPanel, 'numeric');
            app.NPLOTEditField.Value = 256;
            app.VmaxEditFieldLabel = uilabel(app.DiagnosticsPanel);
            app.VmaxEditFieldLabel.Text = 'Vmax';
            app.VmaxEditField = uieditfield(app.DiagnosticsPanel, 'numeric');
            app.VmaxEditField.Value = 20;
            app.EmaxEditFieldLabel = uilabel(app.DiagnosticsPanel);
            app.EmaxEditFieldLabel.Text = 'Emax';
            app.EmaxEditField = uieditfield(app.DiagnosticsPanel, 'numeric');
            app.EmaxEditField.Value = 10;
            app.BmaxEditFieldLabel = uilabel(app.DiagnosticsPanel);
            app.BmaxEditFieldLabel.Text = 'Bmax';
            app.BmaxEditField = uieditfield(app.DiagnosticsPanel, 'numeric');
            app.BmaxEditField.Value = 0.2;
            app.NVEditFieldLabel = uilabel(app.DiagnosticsPanel);
            app.NVEditFieldLabel.Text = 'NV';
            app.NVEditField = uieditfield(app.DiagnosticsPanel, 'numeric');
            app.NVEditField.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = kempo1_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end